classdef AnalyseKIMQA_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OutputFolderPanel             matlab.ui.container.Panel
        SelectedOutputFolder          matlab.ui.control.EditField
        SelectedfolderLabel_4         matlab.ui.control.Label
        OutputfolderButton            matlab.ui.control.Button
        ParameterFilePanel            matlab.ui.container.Panel
        SelectedParamFile             matlab.ui.control.EditField
        SelectedfileEditFieldLabel_7  matlab.ui.control.Label
        ThisaretheparametersrequiredtomatchKIMandrobottracesintimeLabel  matlab.ui.control.Label
        SelectfileButton              matlab.ui.control.Button
        CoordinateFilePanel           matlab.ui.container.Panel
        SelectedCoordFile             matlab.ui.control.EditField
        SelectedfileEditFieldLabel_6  matlab.ui.control.Label
        FormatxyzspecifiedinEclipseElektaconventionLabel  matlab.ui.control.Label
        ThisisthecoordinatesofthemarkerswithrespecttotheisocentreLabel  matlab.ui.control.Label
        SelectFileButton_2            matlab.ui.control.Button
        KIMlogPanel                   matlab.ui.container.Panel
        SelectedKimFolder             matlab.ui.control.EditField
        SelectedfolderLabel_3         matlab.ui.control.Label
        ThisisKIMgeneratedtrajectoryfileLabel  matlab.ui.control.Label
        SelectFolderButton            matlab.ui.control.Button
        MotionTracePanel              matlab.ui.container.Panel
        SelectedMotionFile            matlab.ui.control.EditField
        SelectedfileEditFieldLabel_5  matlab.ui.control.Label
        FormattTxTyTzrxryrzLabel      matlab.ui.control.Label
        SelectFileButton              matlab.ui.control.Button
        StaticShiftsmmPanel           matlab.ui.container.Panel
        VertShift                     matlab.ui.control.EditField
        VertAPLabel                   matlab.ui.control.Label
        LongShift                     matlab.ui.control.EditField
        LongSILabel                   matlab.ui.control.Label
        LateralShift                  matlab.ui.control.EditField
        LateralLRLabel                matlab.ui.control.Label
        LinacVendorButtonGroup        matlab.ui.container.ButtonGroup
        ElektaButton                  matlab.ui.control.RadioButton
        VarianButton                  matlab.ui.control.RadioButton
        AnalysistypeButtonGroup       matlab.ui.container.ButtonGroup
        StaticButton                  matlab.ui.control.RadioButton
        TreatmentInterruptButton      matlab.ui.control.RadioButton
        DynamicButton                 matlab.ui.control.RadioButton
        AnalyseButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            global KIM
            if app.SelectedKimFolder.Visible
                folder_name = uigetdir(app.SelectedKimFolder.Value, 'Please select the folder containing MarkerLocation_GA file/s');
            else
                folder_name = uigetdir('C:\', 'Please select the folder containing MarkerLocation_GA file/s');
            end
            if ~isequal(folder_name,0)
                app.SelectedKimFolder.Value = folder_name;
                app.SelectedKimFolder.Visible = 1;
                KIM.KIMTrajFolder=folder_name;
            end

        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            global KIM
            if app.SelectedMotionFile.Visible
                [app_path, ~, ~] = fileparts(app.SelectedMotionFile.Value);
                [FileName,PathName,~] = uigetfile([app_path '\*.txt'], 'Please select the motion trajectory file (either Hexamotion or Robot');
            else
                [app_path, ~, ~] = fileparts(mfilename('fullpath'));
                [FileName,PathName,~] = uigetfile([app_path '\*.txt'], 'Please select the motion trajectory file (either Hexamotion or Robot');
            end
            if ~isequal(FileName,0)
                app.SelectedMotionFile.Value = [PathName FileName];
                app.SelectedMotionFile.Visible = 1;
                KIM.KIMRobotFile=[PathName FileName];
            end
        end

        % Callback function
        function SelectCoordinateFileButtonPushed(app, event)

        end

        % Callback function
        function AnalyseButtonPushed(app, event)

        end

        % Button pushed function: SelectFileButton_2
        function SelectFileButton_2Pushed(app, event)
            global KIM
            if app.SelectedCoordFile.Visible
                [prev_path, ~, ~] = fileparts(app.SelectedCoordFile.Value);
                [FileName,PathName,~] = uigetfile([prev_path '\*.txt'], 'Please select the marker co-ordinate file');
            else
                [FileName,PathName,~] = uigetfile('C:\*.txt', 'Please select the marker co-ordinate file');
            end

            if ~isequal(FileName,0)
                app.SelectedCoordFile.Value = [PathName FileName];
                app.SelectedCoordFile.Visible = 1;
                KIM.KIMcoordFile=[PathName FileName];
            end
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed2(app, event)
            global KIM
            %KIM.handles=handles;
            if app.SelectedKimFolder.Visible && app.SelectedCoordFile.Visible && app.SelectedOutputFolder.Visible
                common_components = 1;
            end
            if common_components && app.SelectedMotionFile.Visible && app.SelectedParamFile.Visible
                if app.DynamicButton.Value
                    % Dynamicloc(KIM.KIMRobotFile, KIM.KIMTrajFolder, KIM.KIMcoordFile, KIM.KIMparamFile);
                    AnalyseKIMqa(KIM)
                elseif app.TreatmentInterruptButton.Value
                    % TreatmentInt(KIM.KIMRobotFile, KIM.KIMTrajFolder, KIM.KIMcoordFile, KIM.KIMparamFile);
                    AnalyseKIMqa(KIM)
                end
            elseif app.StaticButton.Value
                if common_components && exist(KIM.value_AP,"var") && exist(KIM.value_LR,"var") && exist(KIM.value_SI,"var")
                    Staticloc(KIM.value_LR, KIM.value_SI, KIM.value_AP, KIM.KIMTrajFolder, KIM.KIMcoordFile);
                else
                    msgbox({'Please set all file and folder locations and the static shifts (enter ''0'' if no shift in that direction)'; 'before clicking analyse'})
                end
            else
                msgbox('Please set all file and folder locations before clicking analyse')
            end
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            global KIM
            if app.SelectedParamFile.Visible
                [prev_path, ~, ~] = fileparts(app.SelectedParamFile.Value);
                [FileName,PathName,~] = uigetfile([prev_path '\*.txt'], 'Please select the parameter file');
            else
                [FileName,PathName,~] = uigetfile('C:\*.txt', 'Please select the parameter file');
            end
            if ~isequal(FileName,0)
                app.SelectedParamFile.Value = [PathName FileName];
                app.SelectedParamFile.Visible = 1;
                KIM.KIMparamFile=[PathName FileName];
            end
        end

        % Button pushed function: OutputfolderButton
        function OutputfolderButtonPushed(app, event)
            global KIM
            if app.SelectedOutputFolder.Visible
                folder_name = uigetdir(app.SelectedOutputFolder.Value, 'Please select the folder to save the analysis in');
            else
                folder_name = uigetdir('C:\', 'Please select the folder to save the analysis in');
            end
            if ~isequal(folder_name,0)
                app.SelectedOutputFolder.Value = folder_name;
                app.SelectedOutputFolder.Visible = 1;
                KIM.KIMOutputFolder=folder_name;
            end
        end

        % Selection changed function: AnalysistypeButtonGroup
        function AnalysistypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.AnalysistypeButtonGroup.SelectedObject;
            if strcmpi(selectedButton.Text,'Static')
                app.StaticShiftsmmPanel.Visible = 1;
                app.ParameterFilePanel.Visible = 0;
                app.MotionTracePanel.Visible = 0;
            else
                app.StaticShiftsmmPanel.Visible = 0;
                app.ParameterFilePanel.Visible = 1;
                app.MotionTracePanel.Visible = 1;
            end
        end

        % Value changed function: LateralShift
        function LateralShiftValueChanged(app, event)
            global KIM
            value_LR = app.LateralShift.Value;
            KIM.value_LR = value_LR;
        end

        % Value changed function: LongShift
        function LongShiftValueChanged(app, event)
            global KIM
            value_SI = app.LongShift.Value;
            KIM.value_SI = value_SI;
        end

        % Value changed function: VertShift
        function VertShiftValueChanged(app, event)
            global KIM
            value_AP = app.VertShift.Value;
            KIM.value_AP = value_AP;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AnalyseButton = uibutton(app.UIFigure, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed2, true);
            app.AnalyseButton.Text = 'Analyse';
            app.AnalysistypeButtonGroup = uibuttongroup(app.UIFigure);
            app.AnalysistypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalysistypeButtonGroupSelectionChanged, true);
            app.AnalysistypeButtonGroup.Title = 'Analysis type';
            app.DynamicButton = uiradiobutton(app.AnalysistypeButtonGroup);
            app.DynamicButton.Text = 'Dynamic';
            app.DynamicButton.Value = true;
            app.TreatmentInterruptButton = uiradiobutton(app.AnalysistypeButtonGroup);
            app.TreatmentInterruptButton.Text = 'Treatment Interrupt';
            app.StaticButton = uiradiobutton(app.AnalysistypeButtonGroup);
            app.StaticButton.Text = 'Static';
            app.LinacVendorButtonGroup = uibuttongroup(app.UIFigure);
            app.LinacVendorButtonGroup.Title = 'Linac Vendor';
            app.VarianButton = uiradiobutton(app.LinacVendorButtonGroup);
            app.VarianButton.Text = 'Varian';
            app.VarianButton.Value = true;
            app.ElektaButton = uiradiobutton(app.LinacVendorButtonGroup);
            app.ElektaButton.Text = 'Elekta';
            app.StaticShiftsmmPanel = uipanel(app.UIFigure);
            app.StaticShiftsmmPanel.Title = 'Static Shifts (mm)';
            app.StaticShiftsmmPanel.Visible = 'off';
            app.LateralLRLabel = uilabel(app.StaticShiftsmmPanel);
            app.LateralLRLabel.Text = 'Lateral (LR):';
            app.LateralShift = uieditfield(app.StaticShiftsmmPanel, 'text');
            app.LateralShift.ValueChangedFcn = createCallbackFcn(app, @LateralShiftValueChanged, true);
            app.LongSILabel = uilabel(app.StaticShiftsmmPanel);
            app.LongSILabel.Text = 'Long (SI):';
            app.LongShift = uieditfield(app.StaticShiftsmmPanel, 'text');
            app.LongShift.ValueChangedFcn = createCallbackFcn(app, @LongShiftValueChanged, true);
            app.VertAPLabel = uilabel(app.StaticShiftsmmPanel);
            app.VertAPLabel.Text = 'Vert (AP):';
            app.VertShift = uieditfield(app.StaticShiftsmmPanel, 'text');
            app.VertShift.ValueChangedFcn = createCallbackFcn(app, @VertShiftValueChanged, true);
            app.MotionTracePanel = uipanel(app.UIFigure);
            app.MotionTracePanel.Title = 'Motion Trace';
            app.SelectFileButton = uibutton(app.MotionTracePanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.FormattTxTyTzrxryrzLabel = uilabel(app.MotionTracePanel);
            app.FormattTxTyTzrxryrzLabel.Text = {'Robot motion format: t Tx Ty Tz rx ry rz'; 'Hexamotion format: x y z; is assumed to operate @ 50 Hz'};
            app.SelectedfileEditFieldLabel_5 = uilabel(app.MotionTracePanel);
            app.SelectedfileEditFieldLabel_5.Text = 'Selected file:';
            app.SelectedMotionFile = uieditfield(app.MotionTracePanel, 'text');
            app.SelectedMotionFile.Editable = 'off';
            app.SelectedMotionFile.Visible = 'off';
            app.KIMlogPanel = uipanel(app.UIFigure);
            app.KIMlogPanel.Title = 'KIM log';
            app.SelectFolderButton = uibutton(app.KIMlogPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.ThisisKIMgeneratedtrajectoryfileLabel = uilabel(app.KIMlogPanel);
            app.ThisisKIMgeneratedtrajectoryfileLabel.Text = 'This is KIM generated trajectory file';
            app.SelectedfolderLabel_3 = uilabel(app.KIMlogPanel);
            app.SelectedfolderLabel_3.Text = 'Selected folder:';
            app.SelectedKimFolder = uieditfield(app.KIMlogPanel, 'text');
            app.SelectedKimFolder.Editable = 'off';
            app.SelectedKimFolder.Visible = 'off';
            app.CoordinateFilePanel = uipanel(app.UIFigure);
            app.CoordinateFilePanel.Title = 'Co-ordinate File';
            app.SelectFileButton_2 = uibutton(app.CoordinateFilePanel, 'push');
            app.SelectFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButton_2Pushed, true);
            app.SelectFileButton_2.Text = 'Select File';
            app.ThisisthecoordinatesofthemarkerswithrespecttotheisocentreLabel = uilabel(app.CoordinateFilePanel);
            app.ThisisthecoordinatesofthemarkerswithrespecttotheisocentreLabel.Text = 'This is the coordinates of the markers with respect to the isocentre';
            app.FormatxyzspecifiedinEclipseElektaconventionLabel = uilabel(app.CoordinateFilePanel);
            app.FormatxyzspecifiedinEclipseElektaconventionLabel.Text = 'Format: x y z; specified in Eclipse/Elekta convention';
            app.SelectedfileEditFieldLabel_6 = uilabel(app.CoordinateFilePanel);
            app.SelectedfileEditFieldLabel_6.Text = 'Selected file:';
            app.SelectedCoordFile = uieditfield(app.CoordinateFilePanel, 'text');
            app.SelectedCoordFile.Editable = 'off';
            app.SelectedCoordFile.Visible = 'off';
            app.ParameterFilePanel = uipanel(app.UIFigure);
            app.ParameterFilePanel.Title = 'Parameter File';
            app.SelectfileButton = uibutton(app.ParameterFilePanel, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.ThisaretheparametersrequiredtomatchKIMandrobottracesintimeLabel = uilabel(app.ParameterFilePanel);
            app.ThisaretheparametersrequiredtomatchKIMandrobottracesintimeLabel.Text = 'This are the parameters required to match KIM and robot traces in time';
            app.SelectedfileEditFieldLabel_7 = uilabel(app.ParameterFilePanel);
            app.SelectedfileEditFieldLabel_7.Text = 'Selected file:';
            app.SelectedParamFile = uieditfield(app.ParameterFilePanel, 'text');
            app.SelectedParamFile.Editable = 'off';
            app.SelectedParamFile.Visible = 'off';
            app.OutputFolderPanel = uipanel(app.UIFigure);
            app.OutputFolderPanel.Title = 'Output Folder';
            app.OutputfolderButton = uibutton(app.OutputFolderPanel, 'push');
            app.OutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputfolderButtonPushed, true);
            app.OutputfolderButton.Text = 'Select folder';
            app.SelectedfolderLabel_4 = uilabel(app.OutputFolderPanel);
            app.SelectedfolderLabel_4.Text = 'Selected folder:';
            app.SelectedOutputFolder = uieditfield(app.OutputFolderPanel, 'text');
            app.SelectedOutputFolder.Editable = 'off';
            app.SelectedOutputFolder.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalyseKIMQA_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end