classdef App_Dynamic_loc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SelectedParamFile             matlab.ui.control.EditField
        SelectedfileEditFieldLabel_4  matlab.ui.control.Label
        SelectedCoordFile             matlab.ui.control.EditField
        SelectedfileEditFieldLabel_3  matlab.ui.control.Label
        SelectedMotionFile            matlab.ui.control.EditField
        SelectedfileEditFieldLabel_2  matlab.ui.control.Label
        SelectedKimFolder             matlab.ui.control.EditField
        SelectedfolderLabel           matlab.ui.control.Label
        MotiontraceLabel              matlab.ui.control.Label
        KIMgeneratedtrajectoryfileLabel  matlab.ui.control.Label
        FormattTxTyTzrxryrzLabel      matlab.ui.control.Label
        FormatxyzspecifiedinEclipseElektaconventionLabel  matlab.ui.control.Label
        ParametertomatchKIMandrobottracesintimeLabel  matlab.ui.control.Label
        CoordinatesofthemarkerswithrespecttotheisocentreLabel  matlab.ui.control.Label
        SelecttheparameterfileButton  matlab.ui.control.Button
        AnalyseButton                 matlab.ui.control.Button
        SelectcoordinateFileButton    matlab.ui.control.Button
        SelectRobotTrajFileButton     matlab.ui.control.Button
        SelectKIMTrajFolderButton     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectKIMTrajFolderButton
        function SelectKIMTrajFolderButtonPushed(app, event)
            global KIM
            folder_name = uigetdir('C:\', 'Please select the folder containing MarkerLocation_GA file/s');
            app.SelectedKimFolder.Value = folder_name;
            app.SelectedKimFolder.Visible = 'on';
            KIM.KIMTrajFolder=folder_name;

        end

        % Button pushed function: SelectRobotTrajFileButton
        function SelectRobotTrajFileButtonPushed(app, event)
            global KIM
            [FileName,PathName,~] = uigetfile('*.txt', 'Please select the motion trajectory file (either Hexamotion or Robot');
            app.SelectedMotionFile.Value = [PathName FileName];
            app.SelectedMotionFile.Visible = 1;
            KIM.KIMRobotFile=[PathName FileName];
        end

        % Callback function
        function SelectCoordinateFileButtonPushed(app, event)

        end

        % Callback function
        function AnalyseButtonPushed(app, event)
            

        end

        % Button pushed function: SelectcoordinateFileButton
        function SelectcoordinateFileButtonPushed(app, event)
            global KIM
            [FileName,PathName,~] = uigetfile('*.txt', 'Please select the marker co-ordinate file');
            app.SelectedCoordFile.Value = [PathName FileName];
            app.SelectedCoordFile.Visible = 1;
            KIM.KIMcoordFile=[PathName FileName];
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed2(app, event)
            global KIM
            %KIM.handles=handles;
            Dynamicloc(KIM.KIMRobotFile, KIM.KIMTrajFolder, KIM.KIMcoordFile, KIM.KIMparamFile)
        end

        % Button pushed function: SelecttheparameterfileButton
        function SelecttheparameterfileButtonPushed(app, event)
            global KIM
            [FileName,PathName,~] = uigetfile('*.txt', 'Please select the parameter file');
            app.SelectedParamFile.Value = [PathName FileName];
            app.SelectedParamFile.Visible = 1;
            KIM.KIMparamFile=[PathName FileName];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectKIMTrajFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectKIMTrajFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectKIMTrajFolderButtonPushed, true);
            app.SelectKIMTrajFolderButton.Text = 'Select KIM Traj Folder';
            app.SelectRobotTrajFileButton = uibutton(app.UIFigure, 'push');
            app.SelectRobotTrajFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRobotTrajFileButtonPushed, true);
            app.SelectRobotTrajFileButton.Text = 'Select Robot Traj File';
            app.SelectcoordinateFileButton = uibutton(app.UIFigure, 'push');
            app.SelectcoordinateFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectcoordinateFileButtonPushed, true);
            app.SelectcoordinateFileButton.Text = 'Select coordinate File';
            app.AnalyseButton = uibutton(app.UIFigure, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed2, true);
            app.AnalyseButton.Text = 'Analyse';
            app.SelecttheparameterfileButton = uibutton(app.UIFigure, 'push');
            app.SelecttheparameterfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttheparameterfileButtonPushed, true);
            app.SelecttheparameterfileButton.Text = 'Select the parameter file';
            app.CoordinatesofthemarkerswithrespecttotheisocentreLabel = uilabel(app.UIFigure);
            app.CoordinatesofthemarkerswithrespecttotheisocentreLabel.Text = '(Coordinates of the markers with respect to the isocentre)';
            app.ParametertomatchKIMandrobottracesintimeLabel = uilabel(app.UIFigure);
            app.ParametertomatchKIMandrobottracesintimeLabel.Text = '(Parameter to match KIM and robot traces in time)';
            app.FormatxyzspecifiedinEclipseElektaconventionLabel = uilabel(app.UIFigure);
            app.FormatxyzspecifiedinEclipseElektaconventionLabel.Text = 'Format: x y z; specified in Eclipse/Elekta convention';
            app.FormattTxTyTzrxryrzLabel = uilabel(app.UIFigure);
            app.FormattTxTyTzrxryrzLabel.Text = {'Robot motion format: t Tx Ty Tz rx ry rz'; 'Hexamotion format: x y z; is assumed to operate @ 50 Hz'};
            app.KIMgeneratedtrajectoryfileLabel = uilabel(app.UIFigure);
            app.KIMgeneratedtrajectoryfileLabel.Text = '(KIM generated trajectory file)';
            app.MotiontraceLabel = uilabel(app.UIFigure);
            app.MotiontraceLabel.Text = '(Motion trace)';
            app.SelectedfolderLabel = uilabel(app.UIFigure);
            app.SelectedfolderLabel.Text = 'Selected folder:';
            app.SelectedKimFolder = uieditfield(app.UIFigure, 'text');
            app.SelectedKimFolder.Editable = 'off';
            app.SelectedKimFolder.Visible = 'off';
            app.SelectedfileEditFieldLabel_2 = uilabel(app.UIFigure);
            app.SelectedfileEditFieldLabel_2.Text = 'Selected file:';
            app.SelectedMotionFile = uieditfield(app.UIFigure, 'text');
            app.SelectedMotionFile.Editable = 'off';
            app.SelectedMotionFile.Visible = 'off';
            app.SelectedfileEditFieldLabel_3 = uilabel(app.UIFigure);
            app.SelectedfileEditFieldLabel_3.Text = 'Selected file:';
            app.SelectedCoordFile = uieditfield(app.UIFigure, 'text');
            app.SelectedCoordFile.Editable = 'off';
            app.SelectedCoordFile.Visible = 'off';
            app.SelectedfileEditFieldLabel_4 = uilabel(app.UIFigure);
            app.SelectedfileEditFieldLabel_4.Text = 'Selected file:';
            app.SelectedParamFile = uieditfield(app.UIFigure, 'text');
            app.SelectedParamFile.Editable = 'off';
            app.SelectedParamFile.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Dynamic_loc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end