classdef App_treat_int < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ParametertomatchKIMandrobottracesintimeLabel  matlab.ui.control.Label
        FormatxyzinEclipseElektaconventionLabel  matlab.ui.control.Label
        CoordinatesofthemarkerswithrespecttotheisocentreLabel  matlab.ui.control.Label
        FormattTxTyTzrxryrzLabel   matlab.ui.control.Label
        RobottracesLabel           matlab.ui.control.Label
        KIMgeneratedtrajectoryfileLabel  matlab.ui.control.Label
        SelecttheparamfileButton   matlab.ui.control.Button
        AnalyseButton              matlab.ui.control.Button
        SelectcoordFileButton      matlab.ui.control.Button
        SelectRobotTrajFileButton  matlab.ui.control.Button
        SelectKIMTrajFolderButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectKIMTrajFolderButton
        function SelectKIMTrajFolderButtonPushed(app, event)
            global KIM
            folder_name = uigetdir('E:\');     
            KIM.KIMTrajFolder=folder_name;
     
        end

        % Button pushed function: SelectRobotTrajFileButton
        function SelectRobotTrajFileButtonPushed(app, event)
          global KIM
          [FileName,PathName,~] = uigetfile('E:\*.txt');  
          KIM.KIMRobotFile=[PathName FileName];
        end

        % Callback function
        function SelectCoordinateFileButtonPushed(app, event)
      
        end

        % Callback function
        function AnalyseButtonPushed(app, event)
            global KIM
            %KIM.handles=handles;
            
        end

        % Button pushed function: SelectcoordFileButton
        function SelectcoordFileButtonPushed(app, event)
        global KIM
        [FileName,PathName,~] = uigetfile('E:\*.txt');
        KIM.KIMcoordFile=[PathName FileName]
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed2(app, event)
            global KIM
            %KIM.handles=handles;
            TreatmentInt(KIM.KIMRobotFile, KIM.KIMTrajFolder, KIM.KIMcoordFile, KIM.KIMparamFile)
        end

        % Button pushed function: SelecttheparamfileButton
        function SelecttheparamfileButtonPushed(app, event)
        global KIM
        [FileName,PathName,~] = uigetfile('E:\*.txt');
        KIM.KIMparamFile=[PathName FileName]    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectKIMTrajFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectKIMTrajFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectKIMTrajFolderButtonPushed, true);
            app.SelectKIMTrajFolderButton.Text = 'Select KIM Traj Folder';
            app.SelectRobotTrajFileButton = uibutton(app.UIFigure, 'push');
            app.SelectRobotTrajFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRobotTrajFileButtonPushed, true);
            app.SelectRobotTrajFileButton.Text = 'Select Robot Traj File';
            app.SelectcoordFileButton = uibutton(app.UIFigure, 'push');
            app.SelectcoordFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectcoordFileButtonPushed, true);
            app.SelectcoordFileButton.Text = 'Select coord File';
            app.AnalyseButton = uibutton(app.UIFigure, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed2, true);
            app.AnalyseButton.Text = 'Analyse';
            app.SelecttheparamfileButton = uibutton(app.UIFigure, 'push');
            app.SelecttheparamfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttheparamfileButtonPushed, true);
            app.SelecttheparamfileButton.Text = 'Select the param file';
            app.KIMgeneratedtrajectoryfileLabel = uilabel(app.UIFigure);
            app.KIMgeneratedtrajectoryfileLabel.Text = '(KIM generated trajectory file)';
            app.RobottracesLabel = uilabel(app.UIFigure);
            app.RobottracesLabel.Text = '(Robot traces)';
            app.FormattTxTyTzrxryrzLabel = uilabel(app.UIFigure);
            app.FormattTxTyTzrxryrzLabel.Text = 'Format: t, Tx, Ty, Tz, rx, ry, rz';
            app.CoordinatesofthemarkerswithrespecttotheisocentreLabel = uilabel(app.UIFigure);
            app.CoordinatesofthemarkerswithrespecttotheisocentreLabel.Text = 'Coordinates of the markers with respect to the isocentre';
            app.FormatxyzinEclipseElektaconventionLabel = uilabel(app.UIFigure);
            app.FormatxyzinEclipseElektaconventionLabel.Text = 'Format: x, y, z in Eclipse/Elekta convention';
            app.ParametertomatchKIMandrobottracesintimeLabel = uilabel(app.UIFigure);
            app.ParametertomatchKIMandrobottracesintimeLabel.Text = '(Parameter to match KIM and robot traces in time)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_treat_int

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end